classdef OutPutSlx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        TabGroup             matlab.ui.container.TabGroup
        Tab                  matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        GridLayout4          matlab.ui.container.GridLayout
        Button_2             matlab.ui.control.Button
        Button               matlab.ui.control.Button
        TransTemptFilePanel  matlab.ui.container.Panel
        GridLayout5          matlab.ui.container.GridLayout
        TransTemptTextArea   matlab.ui.control.TextArea
        Label                matlab.ui.control.Label
        Tab_2                matlab.ui.container.Tab
        GridLayout3_2        matlab.ui.container.GridLayout
        GridLayout4_2        matlab.ui.container.GridLayout
        Button_5             matlab.ui.control.Button
        Button_4             matlab.ui.control.Button
        Button_3             matlab.ui.control.Button
        TransPowerFilePanel  matlab.ui.container.Panel
        GridLayout6          matlab.ui.container.GridLayout
        TransPowerTextArea   matlab.ui.control.TextArea
        Label_2              matlab.ui.control.Label
        GridLayout2          matlab.ui.container.GridLayout
        StateSpace           matlab.ui.control.Button
        Circuit              matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallerApp % Description
    end
    properties (Access = public)
        ModelStruct;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, CallerApp)
            Cauer3D.Export.OutPutSlxStartup(app,CallerApp);
            app.CallerApp = CallerApp;
        end

        % Button pushed function: Circuit
        function CircuitButtonPushed(app, event)
            Cauer3D.Export.OutPutCircuit(app,app.CallerApp);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            Cauer3D.Export.OutPutTTFImport(app,app.CallerApp);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            Cauer3D.Export.OutPutTTFClear(app,app.CallerApp);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            Cauer3D.Export.OutPutTPFImport(app,app.CallerApp);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            Cauer3D.Export.OutPutTPFClear(app,app.CallerApp);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            Cauer3D.Export.TrainingSetPowerGen(app);
        end

        % Button pushed function: StateSpace
        function StateSpaceButtonPushed(app, event)
            Cauer3D.Export.OutPutStateSpace(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.Circuit = uibutton(app.GridLayout2, 'push');
            app.Circuit.ButtonPushedFcn = createCallbackFcn(app, @CircuitButtonPushed, true);
            app.Circuit.Text = '导出为电路模型';
            app.StateSpace = uibutton(app.GridLayout2, 'push');
            app.StateSpace.ButtonPushedFcn = createCallbackFcn(app, @StateSpaceButtonPushed, true);
            app.StateSpace.Text = '导出为状态空间';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '瞬态热仿真文件';
            app.GridLayout3 = uigridlayout(app.Tab);
            app.TransTemptFilePanel = uipanel(app.GridLayout3);
            app.TransTemptFilePanel.Title = 'TransTemptFile';
            app.GridLayout5 = uigridlayout(app.TransTemptFilePanel);
            app.Label = uilabel(app.GridLayout5);
            app.Label.Interpreter = 'latex';
            app.Label.Text = {'请选择'; ''; '瞬态热仿真'; ''; '结果文件'};
            app.TransTemptTextArea = uitextarea(app.GridLayout5);
            app.TransTemptTextArea.Tag = 'TransTemptTextArea';
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.Button = uibutton(app.GridLayout4, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '导入';
            app.Button_2 = uibutton(app.GridLayout4, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '清空';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '功率设置文件';
            app.GridLayout3_2 = uigridlayout(app.Tab_2);
            app.TransPowerFilePanel = uipanel(app.GridLayout3_2);
            app.TransPowerFilePanel.Title = 'TransPowerFile';
            app.GridLayout6 = uigridlayout(app.TransPowerFilePanel);
            app.Label_2 = uilabel(app.GridLayout6);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = {'请选择'; ''; '该热仿真的'; ''; '功率设置文件'};
            app.TransPowerTextArea = uitextarea(app.GridLayout6);
            app.TransPowerTextArea.Tag = 'TransPowerTextArea';
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.Button_3 = uibutton(app.GridLayout4_2, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '导入';
            app.Button_4 = uibutton(app.GridLayout4_2, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '清空';
            app.Button_5 = uibutton(app.GridLayout4_2, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '生成';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OutPutSlx(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end