classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FilesMenu              matlab.ui.container.Menu
        SaveMenu               matlab.ui.container.Menu
        OpenMenu               matlab.ui.container.Menu
        NewMenu                matlab.ui.container.Menu
        AboutMenu              matlab.ui.container.Menu
        GridLayout             matlab.ui.container.GridLayout
        TransientThermPanel    matlab.ui.container.Panel
        GridLayout2_3          matlab.ui.container.GridLayout
        Panel_14               matlab.ui.container.Panel
        GridLayout3_6          matlab.ui.container.GridLayout
        TabGroup_2             matlab.ui.container.TabGroup
        Tab_3                  matlab.ui.container.Tab
        GridLayout11           matlab.ui.container.GridLayout
        ParPoolEditField       matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        Tab_4                  matlab.ui.container.Tab
        GridLayout10_2         matlab.ui.container.GridLayout
        GridLayout12           matlab.ui.container.GridLayout
        StepSizeEditField      matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        Tab_5                  matlab.ui.container.Tab
        GridLayout13           matlab.ui.container.GridLayout
        GridLayout14           matlab.ui.container.GridLayout
        slxDirFolderTextArea   matlab.ui.control.TextArea
        SimulinkTextAreaLabel  matlab.ui.control.Label
        GridLayout4_6          matlab.ui.container.GridLayout
        slxDirFolderClear      matlab.ui.control.Button
        slxDirFolderSelect     matlab.ui.control.Button
        TabGroup               matlab.ui.container.TabGroup
        Tab                    matlab.ui.container.Tab
        GridLayout9            matlab.ui.container.GridLayout
        Panel_9                matlab.ui.container.Panel
        GridLayout3_3          matlab.ui.container.GridLayout
        TransientThermTable    matlab.ui.control.Table
        GridLayout4_3          matlab.ui.container.GridLayout
        TransientThermClear    matlab.ui.control.Button
        TransientThermImport   matlab.ui.control.Button
        Tab_2                  matlab.ui.container.Tab
        GridLayout10           matlab.ui.container.GridLayout
        Panel_13               matlab.ui.container.Panel
        GridLayout3_5          matlab.ui.container.GridLayout
        TransientPowerTable    matlab.ui.control.Table
        GridLayout4_5          matlab.ui.container.GridLayout
        TransientPowerGen      matlab.ui.control.Button
        TransientPowerClear    matlab.ui.control.Button
        TransientPowerImport   matlab.ui.control.Button
        Panel_11               matlab.ui.container.Panel
        GridLayout7_3          matlab.ui.container.GridLayout
        GridLayout15           matlab.ui.container.GridLayout
        MessageTextArea        matlab.ui.control.TextArea
        Label_4                matlab.ui.control.Label
        GridLayout8_3          matlab.ui.container.GridLayout
        ViewModelStructButton  matlab.ui.control.Button
        StartStopButton        matlab.ui.control.StateButton
        SteadyThermPanel       matlab.ui.container.Panel
        GridLayout2_2          matlab.ui.container.GridLayout
        Panel_12               matlab.ui.container.Panel
        GridLayout3_4          matlab.ui.container.GridLayout
        SteadyPowerTable       matlab.ui.control.Table
        GridLayout4_4          matlab.ui.container.GridLayout
        SteadyPowerGen         matlab.ui.control.Button
        SteadyPowerClear       matlab.ui.control.Button
        SteadyPowerImport      matlab.ui.control.Button
        Panel_8                matlab.ui.container.Panel
        GridLayout7_2          matlab.ui.container.GridLayout
        GridLayout8_2          matlab.ui.container.GridLayout
        DrawButton             matlab.ui.control.Button
        OutPutToSlx            matlab.ui.control.Button
        Panel_6                matlab.ui.container.Panel
        GridLayout3_2          matlab.ui.container.GridLayout
        SteadyThermTable       matlab.ui.control.Table
        GridLayout4_2          matlab.ui.container.GridLayout
        SteadyThermClear       matlab.ui.control.Button
        SteadyThermImport      matlab.ui.control.Button
        ModelStructPanel       matlab.ui.container.Panel
        GridLayout2            matlab.ui.container.GridLayout
        Panel_4                matlab.ui.container.Panel
        GridLayout7            matlab.ui.container.GridLayout
        NomenclatureLabel      matlab.ui.control.Label
        GridLayout8            matlab.ui.container.GridLayout
        NomenclatureDropDown   matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Panel_3                matlab.ui.container.Panel
        GridLayout5            matlab.ui.container.GridLayout
        GridLayout6            matlab.ui.container.GridLayout
        LinkGen                matlab.ui.control.Button
        GenMLinkClear          matlab.ui.control.Button
        GenMLinkImport         matlab.ui.control.Button
        GenMLinkTable          matlab.ui.control.Table
        Panel_2                matlab.ui.container.Panel
        GridLayout3            matlab.ui.container.GridLayout
        GenMTherTable          matlab.ui.control.Table
        GridLayout4            matlab.ui.container.GridLayout
        GenMTherClear          matlab.ui.control.Button
        GenMTherImport         matlab.ui.control.Button
    end

    
    properties (Access = public)
        ModelStruct
        UsersData
    end
    
    properties (Access = private)
        SubApp
    end
    
    properties (Access = public)
        Notice  matlab.ui.Figure
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            Cauer3D.AppStartup(app);
        end

        % Button pushed function: GenMTherImport
        function GenMTherImportButtonPushed(app, event)
            Cauer3D.UI.GenMTherImport(app,event);
        end

        % Button pushed function: GenMTherClear
        function GenMTherClearButtonPushed(app, event)
            Cauer3D.UI.GenMTherClear(app,event);
        end

        % Cell edit callback: GenMTherTable
        function GenMTherTableCellEdit(app, event)
            Cauer3D.UI.GenMTherTable(app,event);
        end

        % Button pushed function: GenMLinkImport
        function GenMLinkImportButtonPushed(app, event)
            Cauer3D.UI.GenMLinkImport(app,event);
        end

        % Button pushed function: GenMLinkClear
        function GenMLinkClearButtonPushed(app, event)
            Cauer3D.UI.GenMLinkClear(app,event);
        end

        % Cell edit callback: GenMLinkTable
        function GenMLinkTableCellEdit(app, event)
            Cauer3D.UI.GenMLinkTable(app,event);
        end

        % Drop down opening function: NomenclatureDropDown
        function NomenclatureDropDownOpening(app, event)
            Cauer3D.UI.NomenclatureDropDown(app,event);
        end

        % Value changed function: NomenclatureDropDown
        function NomenclatureDropDownValueChanged(app, event)
            Cauer3D.UI.NomenclatureDropDown(app,event);
        end

        % Button pushed function: SteadyThermImport
        function SteadyThermImportButtonPushed(app, event)
            Cauer3D.UI.SteadyThermImport(app,event);
        end

        % Button pushed function: SteadyThermClear
        function SteadyThermClearButtonPushed(app, event)
            Cauer3D.UI.SteadyThermClear(app,event);
        end

        % Cell edit callback: SteadyThermTable
        function SteadyThermTableCellEdit(app, event)
            Cauer3D.UI.SteadyThermTable(app,event);
        end

        % Cell edit callback: TransientThermTable
        function TransientThermTableCellEdit(app, event)
            Cauer3D.UI.TransientThermTable(app,event);
        end

        % Button pushed function: TransientThermImport
        function TransientThermImportButtonPushed(app, event)
            Cauer3D.UI.TransientThermImport(app,event);
        end

        % Button pushed function: TransientThermClear
        function TransientThermClearButtonPushed(app, event)
            Cauer3D.UI.TransientThermClear(app,event);
        end

        % Callback function
        function StartStopButtonButtonDown(app, event)
            Cauer3D.UI.StartStopButton(app,event);
        end

        % Button pushed function: SteadyPowerImport
        function SteadyPowerImportButtonPushed(app, event)
            Cauer3D.UI.SteadyPowerImport(app, event);
        end

        % Cell edit callback: SteadyPowerTable
        function SteadyPowerTableCellEdit(app, event)
            Cauer3D.UI.SteadyPowerTable(app, event);
        end

        % Button pushed function: SteadyPowerClear
        function SteadyPowerClearButtonPushed(app, event)
            Cauer3D.UI.SteadyPowerClear(app, event);
        end

        % Cell edit callback: TransientPowerTable
        function TransientPowerTableCellEdit(app, event)
            Cauer3D.UI.TransientPowerTable(app,event);
        end

        % Button pushed function: TransientPowerImport
        function TransientPowerImportButtonPushed(app, event)
            Cauer3D.UI.TransientPowerImport(app,event);
        end

        % Button pushed function: TransientPowerClear
        function TransientPowerClearButtonPushed(app, event)
            Cauer3D.UI.TransientPowerClear(app,event);
        end

        % Callback function
        function GenMLinkTableDisplayDataChanged(app, event)
            Cauer3D.UI.GenMLinkTable(app,event);
        end

        % Value changed function: StartStopButton
        function StartStopButtonValueChanged(app, event)
            Cauer3D.UI.StartStopButton(app,event);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            Cauer3D.UI.CloseAppPreProcess(app,event);
            delete(app);
        end

        % Button pushed function: slxDirFolderSelect
        function slxDirFolderSelectButtonPushed(app, event)
            Cauer3D.UI.slxDirFolderSelect(app,event);
        end

        % Button pushed function: slxDirFolderClear
        function slxDirFolderClearButtonPushed(app, event)
            Cauer3D.UI.slxDirFolderClear(app,event);
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            Cauer3D.UI.SaveMenu(app,event);
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            Cauer3D.UI.OpenMenu(app,event);
        end

        % Button pushed function: ViewModelStructButton
        function ViewModelStructButtonPushed(app, event)
            Cauer3D.UI.ViewModelStruct(app,event);
        end

        % Button pushed function: SteadyPowerGen
        function SteadyPowerGenButtonPushed(app, event)
            Cauer3D.UI.SteadyPowerGen(app,event);
        end

        % Button pushed function: TransientPowerGen
        function TransientPowerGenButtonPushed(app, event)
            Cauer3D.UI.TransientPowerGen(app,event);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            Cauer3D.UI.NewMenu(app,event);
        end

        % Button pushed function: OutPutToSlx
        function OutPutToSlxButtonPushed(app, event)
            app.SubApp.OutPutSlxApp = Cauer3D.Export.OutPutSlx(app);
        end

        % Button pushed function: DrawButton
        function DrawButtonButtonPushed(app, event)
            app.SubApp.DrawApp = Cauer3D.Plot.DrawApp(app);
        end

        % Button pushed function: LinkGen
        function LinkGenButtonPushed(app, event)
            Cauer3D.UI.LinkGen(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = '文件';
            app.SaveMenu = uimenu(app.FilesMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Accelerator = 's';
            app.SaveMenu.Text = '保存';
            app.OpenMenu = uimenu(app.FilesMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = '打开';
            app.NewMenu = uimenu(app.FilesMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Accelerator = 'N';
            app.NewMenu.Text = '新建';
            app.AboutMenu = uimenu(app.UIFigure);
            app.AboutMenu.Text = '关于';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ModelStructPanel = uipanel(app.GridLayout);
            app.ModelStructPanel.Title = '模型生成设置';
            app.GridLayout2 = uigridlayout(app.ModelStructPanel);
            app.Panel_2 = uipanel(app.GridLayout2);
            app.Panel_2.Title = '稳态热仿真';
            app.GridLayout3 = uigridlayout(app.Panel_2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GenMTherImport = uibutton(app.GridLayout4, 'push');
            app.GenMTherImport.ButtonPushedFcn = createCallbackFcn(app, @GenMTherImportButtonPushed, true);
            app.GenMTherImport.Text = '导入';
            app.GenMTherClear = uibutton(app.GridLayout4, 'push');
            app.GenMTherClear.ButtonPushedFcn = createCallbackFcn(app, @GenMTherClearButtonPushed, true);
            app.GenMTherClear.Text = '清空';
            app.GenMTherTable = uitable(app.GridLayout3);
            app.GenMTherTable.ColumnName = {'NodeNameFile'};
            app.GenMTherTable.RowName = {};
            app.GenMTherTable.ColumnEditable = true;
            app.GenMTherTable.CellEditCallback = createCallbackFcn(app, @GenMTherTableCellEdit, true);
            app.GenMTherTable.Tag = 'GenMTherTable';
            app.Panel_3 = uipanel(app.GridLayout2);
            app.Panel_3.Title = '连接关系设置';
            app.GridLayout5 = uigridlayout(app.Panel_3);
            app.GenMLinkTable = uitable(app.GridLayout5);
            app.GenMLinkTable.ColumnName = {'LinkFile'};
            app.GenMLinkTable.RowName = {};
            app.GenMLinkTable.ColumnEditable = true;
            app.GenMLinkTable.CellEditCallback = createCallbackFcn(app, @GenMLinkTableCellEdit, true);
            app.GenMLinkTable.Tag = 'GenMLinkTable';
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GenMLinkImport = uibutton(app.GridLayout6, 'push');
            app.GenMLinkImport.ButtonPushedFcn = createCallbackFcn(app, @GenMLinkImportButtonPushed, true);
            app.GenMLinkImport.Text = '导入';
            app.GenMLinkClear = uibutton(app.GridLayout6, 'push');
            app.GenMLinkClear.ButtonPushedFcn = createCallbackFcn(app, @GenMLinkClearButtonPushed, true);
            app.GenMLinkClear.Text = '清空';
            app.LinkGen = uibutton(app.GridLayout6, 'push');
            app.LinkGen.ButtonPushedFcn = createCallbackFcn(app, @LinkGenButtonPushed, true);
            app.LinkGen.Text = '生成(空的)';
            app.Panel_4 = uipanel(app.GridLayout2);
            app.Panel_4.Title = '节点命名方式';
            app.GridLayout7 = uigridlayout(app.Panel_4);
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.Label = uilabel(app.GridLayout8);
            app.Label.Text = '命名规则';
            app.NomenclatureDropDown = uidropdown(app.GridLayout8);
            app.NomenclatureDropDown.Items = {};
            app.NomenclatureDropDown.DropDownOpeningFcn = createCallbackFcn(app, @NomenclatureDropDownOpening, true);
            app.NomenclatureDropDown.ValueChangedFcn = createCallbackFcn(app, @NomenclatureDropDownValueChanged, true);
            app.NomenclatureDropDown.Tag = 'NomenclatureDropDown';
            app.NomenclatureDropDown.Value = {};
            app.NomenclatureLabel = uilabel(app.GridLayout7);
            app.NomenclatureLabel.Text = '';
            app.SteadyThermPanel = uipanel(app.GridLayout);
            app.SteadyThermPanel.Title = '稳态热阻拟合';
            app.GridLayout2_2 = uigridlayout(app.SteadyThermPanel);
            app.Panel_6 = uipanel(app.GridLayout2_2);
            app.Panel_6.Title = '稳态热仿真';
            app.GridLayout3_2 = uigridlayout(app.Panel_6);
            app.GridLayout4_2 = uigridlayout(app.GridLayout3_2);
            app.SteadyThermImport = uibutton(app.GridLayout4_2, 'push');
            app.SteadyThermImport.ButtonPushedFcn = createCallbackFcn(app, @SteadyThermImportButtonPushed, true);
            app.SteadyThermImport.Text = '导入';
            app.SteadyThermClear = uibutton(app.GridLayout4_2, 'push');
            app.SteadyThermClear.ButtonPushedFcn = createCallbackFcn(app, @SteadyThermClearButtonPushed, true);
            app.SteadyThermClear.Text = '清空';
            app.SteadyThermTable = uitable(app.GridLayout3_2);
            app.SteadyThermTable.ColumnName = {'SteadyTemptFile'};
            app.SteadyThermTable.RowName = {};
            app.SteadyThermTable.ColumnEditable = true;
            app.SteadyThermTable.CellEditCallback = createCallbackFcn(app, @SteadyThermTableCellEdit, true);
            app.SteadyThermTable.Tag = 'SteadyThermTable';
            app.Panel_8 = uipanel(app.GridLayout2_2);
            app.Panel_8.Title = ' ';
            app.GridLayout7_2 = uigridlayout(app.Panel_8);
            app.GridLayout8_2 = uigridlayout(app.GridLayout7_2);
            app.OutPutToSlx = uibutton(app.GridLayout8_2, 'push');
            app.OutPutToSlx.ButtonPushedFcn = createCallbackFcn(app, @OutPutToSlxButtonPushed, true);
            app.OutPutToSlx.Text = '导出模型到Simulink';
            app.DrawButton = uibutton(app.GridLayout8_2, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonButtonPushed, true);
            app.DrawButton.Text = '画图';
            app.Panel_12 = uipanel(app.GridLayout2_2);
            app.Panel_12.Title = '稳态功率设置';
            app.GridLayout3_4 = uigridlayout(app.Panel_12);
            app.GridLayout4_4 = uigridlayout(app.GridLayout3_4);
            app.SteadyPowerImport = uibutton(app.GridLayout4_4, 'push');
            app.SteadyPowerImport.ButtonPushedFcn = createCallbackFcn(app, @SteadyPowerImportButtonPushed, true);
            app.SteadyPowerImport.Text = '导入';
            app.SteadyPowerClear = uibutton(app.GridLayout4_4, 'push');
            app.SteadyPowerClear.ButtonPushedFcn = createCallbackFcn(app, @SteadyPowerClearButtonPushed, true);
            app.SteadyPowerClear.Text = '清空';
            app.SteadyPowerGen = uibutton(app.GridLayout4_4, 'push');
            app.SteadyPowerGen.ButtonPushedFcn = createCallbackFcn(app, @SteadyPowerGenButtonPushed, true);
            app.SteadyPowerGen.Text = '生成(空的)';
            app.SteadyPowerTable = uitable(app.GridLayout3_4);
            app.SteadyPowerTable.ColumnName = {'SteadyPowerFile'};
            app.SteadyPowerTable.RowName = {};
            app.SteadyPowerTable.ColumnEditable = true;
            app.SteadyPowerTable.CellEditCallback = createCallbackFcn(app, @SteadyPowerTableCellEdit, true);
            app.SteadyPowerTable.Tag = 'SteadyPowerTable';
            app.TransientThermPanel = uipanel(app.GridLayout);
            app.TransientThermPanel.Title = '瞬态热阻拟合';
            app.GridLayout2_3 = uigridlayout(app.TransientThermPanel);
            app.Panel_11 = uipanel(app.GridLayout2_3);
            app.Panel_11.Title = ' ';
            app.GridLayout7_3 = uigridlayout(app.Panel_11);
            app.GridLayout8_3 = uigridlayout(app.GridLayout7_3);
            app.StartStopButton = uibutton(app.GridLayout8_3, 'state');
            app.StartStopButton.ValueChangedFcn = createCallbackFcn(app, @StartStopButtonValueChanged, true);
            app.StartStopButton.Text = '开始计算';
            app.ViewModelStructButton = uibutton(app.GridLayout8_3, 'push');
            app.ViewModelStructButton.ButtonPushedFcn = createCallbackFcn(app, @ViewModelStructButtonPushed, true);
            app.ViewModelStructButton.Text = {'查看模型'; '(需要在Matlab环境下)'};
            app.GridLayout15 = uigridlayout(app.GridLayout7_3);
            app.Label_4 = uilabel(app.GridLayout15);
            app.Label_4.Text = {'计'; '算'; '流'; '程'; '信'; '息'; '窗'; '口'};
            app.MessageTextArea = uitextarea(app.GridLayout15);
            app.MessageTextArea.Tag = 'MessageTextArea';
            app.TabGroup = uitabgroup(app.GridLayout2_3);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '热仿真';
            app.GridLayout9 = uigridlayout(app.Tab);
            app.Panel_9 = uipanel(app.GridLayout9);
            app.Panel_9.Title = '瞬态热仿真';
            app.GridLayout3_3 = uigridlayout(app.Panel_9);
            app.GridLayout4_3 = uigridlayout(app.GridLayout3_3);
            app.TransientThermImport = uibutton(app.GridLayout4_3, 'push');
            app.TransientThermImport.ButtonPushedFcn = createCallbackFcn(app, @TransientThermImportButtonPushed, true);
            app.TransientThermImport.Text = '导入';
            app.TransientThermClear = uibutton(app.GridLayout4_3, 'push');
            app.TransientThermClear.ButtonPushedFcn = createCallbackFcn(app, @TransientThermClearButtonPushed, true);
            app.TransientThermClear.Text = '清空';
            app.TransientThermTable = uitable(app.GridLayout3_3);
            app.TransientThermTable.ColumnName = {'TransTemptFile'};
            app.TransientThermTable.RowName = {};
            app.TransientThermTable.ColumnEditable = true;
            app.TransientThermTable.CellEditCallback = createCallbackFcn(app, @TransientThermTableCellEdit, true);
            app.TransientThermTable.Tag = 'TransientThermTable';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '热功率';
            app.GridLayout10 = uigridlayout(app.Tab_2);
            app.Panel_13 = uipanel(app.GridLayout10);
            app.Panel_13.Title = '瞬态功率设置';
            app.GridLayout3_5 = uigridlayout(app.Panel_13);
            app.GridLayout4_5 = uigridlayout(app.GridLayout3_5);
            app.TransientPowerImport = uibutton(app.GridLayout4_5, 'push');
            app.TransientPowerImport.ButtonPushedFcn = createCallbackFcn(app, @TransientPowerImportButtonPushed, true);
            app.TransientPowerImport.Text = '导入';
            app.TransientPowerClear = uibutton(app.GridLayout4_5, 'push');
            app.TransientPowerClear.ButtonPushedFcn = createCallbackFcn(app, @TransientPowerClearButtonPushed, true);
            app.TransientPowerClear.Text = '清空';
            app.TransientPowerGen = uibutton(app.GridLayout4_5, 'push');
            app.TransientPowerGen.ButtonPushedFcn = createCallbackFcn(app, @TransientPowerGenButtonPushed, true);
            app.TransientPowerGen.Text = '生成(空的)';
            app.TransientPowerTable = uitable(app.GridLayout3_5);
            app.TransientPowerTable.ColumnName = {'TransPowerFile'};
            app.TransientPowerTable.RowName = {};
            app.TransientPowerTable.ColumnEditable = true;
            app.TransientPowerTable.CellEditCallback = createCallbackFcn(app, @TransientPowerTableCellEdit, true);
            app.TransientPowerTable.Tag = 'TransientPowerTable';
            app.Panel_14 = uipanel(app.GridLayout2_3);
            app.Panel_14.Title = '其他设置';
            app.GridLayout3_6 = uigridlayout(app.Panel_14);
            app.TabGroup_2 = uitabgroup(app.GridLayout3_6);
            app.Tab_3 = uitab(app.TabGroup_2);
            app.Tab_3.Title = '线程数量';
            app.GridLayout11 = uigridlayout(app.Tab_3);
            app.Label_2 = uilabel(app.GridLayout11);
            app.Label_2.Text = {'线程数'; '(注意内存容量,一般内存会先满载)'};
            app.ParPoolEditField = uieditfield(app.GridLayout11, 'numeric');
            app.ParPoolEditField.Limits = [0 Inf];
            app.ParPoolEditField.Tag = 'ParPoolEditField';
            app.ParPoolEditField.Value = 10;
            app.Tab_4 = uitab(app.TabGroup_2);
            app.Tab_4.Title = '求解步长';
            app.GridLayout10_2 = uigridlayout(app.Tab_4);
            app.GridLayout12 = uigridlayout(app.GridLayout10_2);
            app.Label_3 = uilabel(app.GridLayout12);
            app.Label_3.Text = {'Simulink求解步长'; '(单位:s)'};
            app.StepSizeEditField = uieditfield(app.GridLayout12, 'numeric');
            app.StepSizeEditField.Limits = [0 Inf];
            app.StepSizeEditField.Tag = 'StepSizeEditField';
            app.StepSizeEditField.Value = 0.0001;
            app.Tab_5 = uitab(app.TabGroup_2);
            app.Tab_5.Title = '临时文件';
            app.GridLayout13 = uigridlayout(app.Tab_5);
            app.GridLayout4_6 = uigridlayout(app.GridLayout13);
            app.slxDirFolderSelect = uibutton(app.GridLayout4_6, 'push');
            app.slxDirFolderSelect.ButtonPushedFcn = createCallbackFcn(app, @slxDirFolderSelectButtonPushed, true);
            app.slxDirFolderSelect.Text = '选择文件夹';
            app.slxDirFolderClear = uibutton(app.GridLayout4_6, 'push');
            app.slxDirFolderClear.ButtonPushedFcn = createCallbackFcn(app, @slxDirFolderClearButtonPushed, true);
            app.slxDirFolderClear.Text = '清空';
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.SimulinkTextAreaLabel = uilabel(app.GridLayout14);
            app.SimulinkTextAreaLabel.Text = {'计算用Simulink文件存放位置'; '(未设置则不保存)'};
            app.slxDirFolderTextArea = uitextarea(app.GridLayout14);
            app.slxDirFolderTextArea.Tag = 'slxDirFolderTextArea';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end